classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EStopSwitch             matlab.ui.control.Switch
        TargetStatusGauge       matlab.ui.control.LinearGauge
        TargetStatusGaugeLabel  matlab.ui.control.Label
        EStopLamp               matlab.ui.control.Lamp
        EStopLampLabel          matlab.ui.control.Label
        MoveHeadRightButton     matlab.ui.control.StateButton
        MoveHeadLeftButton      matlab.ui.control.StateButton
        MoveHeadForwardButton   matlab.ui.control.StateButton
        MoveHeadDownButton      matlab.ui.control.StateButton
        MoveHeadBackButton      matlab.ui.control.StateButton
        MoveHeadUpButton        matlab.ui.control.StateButton
        PredefinedImagesLabel   matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        LoadButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        simulation_active % Description
    end
    
    methods (Access = private)
        
        function updateworkspace(app, simulation)
            % Empty for now
        end
        
        function runsimulation(app, mode)
            % Disable simulation changes
            app.simulation_active = true
            display(mode)
            
            switch mode
                case '1'
                    % Run the simulation as-is
                    display("Starting Automatic Simulation")
                    
                    RoboTargeterSimulation(app.UIAxes)

                case '2'
                    % Run the simulation, with a dummy laser
                    display("Starting Manual Laser Simulation")
                    % Enable manual controls for the laser robot
                case '3'
                    % Run the simulation, with a dummy laser
                    display("Starting Manual Target Simulation")
                    % Enable manual controls for the laser robot
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
            end

            % Once simulation has changed, re-enable simulation changes
            app.simulation_active = false
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Update the image and histograms
            updateworkspace(app, app.DropDown.Value);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            if app.simulation_active == true
                % Do Nothing
            else
                updateworkspace(app, app.DropDown.Value);
            end
        end

        % Button pushed function: LoadButton
        function SimulationButtonPushed(app, event)
            if app.simulation_active == true
                % Do Nothing
                uialert(app.UIFigure, 'Simulation is already running. ', 'Image Error');
            else
                % Run simulation
                runsimulation(app, app.DropDown.Value)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Workspace Simulaton')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.LoadButton.Text = 'Run Simulation';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Automatic', 'Manual Laser', 'Manual Target', ''};
            app.DropDown.ItemsData = {'1', '2', '3'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = {'Hide and Seek'; ''};
            app.MoveHeadUpButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadUpButton.Text = 'Move Head Up';
            app.MoveHeadBackButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadBackButton.Text = 'Move Head Back';
            app.MoveHeadDownButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadDownButton.Text = 'Move Head Down';
            app.MoveHeadForwardButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadForwardButton.Text = 'Move Head Forward';
            app.MoveHeadLeftButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadLeftButton.Text = 'Move Head Left';
            app.MoveHeadRightButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadRightButton.Text = 'Move Head Right';
            app.EStopLampLabel = uilabel(app.UIFigure);
            app.EStopLampLabel.Text = 'E Stop';
            app.EStopLamp = uilamp(app.UIFigure);
            app.TargetStatusGaugeLabel = uilabel(app.UIFigure);
            app.TargetStatusGaugeLabel.Text = 'Target Status';
            app.TargetStatusGauge = uigauge(app.UIFigure, 'linear');
            app.EStopSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end