classdef GUITesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ResumeButton            matlab.ui.control.Button
        ESTOPLamp               matlab.ui.control.Lamp
        EmergencyStop           matlab.ui.control.Switch
        EMERGENCYSTOPLabel      matlab.ui.control.Label
        SimulationProgramPanel  matlab.ui.container.Panel
        InitialiseButton        matlab.ui.control.Button
        ManualMovementPanel     matlab.ui.container.Panel
        Joint6Gauge             matlab.ui.control.LinearGauge
        Joint6GaugeLabel        matlab.ui.control.Label
        Joint5Gauge             matlab.ui.control.LinearGauge
        Joint5GaugeLabel        matlab.ui.control.Label
        Joint4Gauge             matlab.ui.control.LinearGauge
        Joint4GaugeLabel        matlab.ui.control.Label
        Joint3Gauge             matlab.ui.control.LinearGauge
        Joint3GaugeLabel        matlab.ui.control.Label
        Joint2Gauge             matlab.ui.control.LinearGauge
        Joint2GaugeLabel        matlab.ui.control.Label
        Joint1Gauge             matlab.ui.control.LinearGauge
        Joint1GaugeLabel        matlab.ui.control.Label
        YAWLabel                matlab.ui.control.Label
        PITCHLabel              matlab.ui.control.Label
        ROLLLabel               matlab.ui.control.Label
        pROLLButton             matlab.ui.control.Button
        nYAWButton              matlab.ui.control.Button
        pPITCHButton            matlab.ui.control.Button
        pYAWButton              matlab.ui.control.Button
        nROLLButton             matlab.ui.control.Button
        nPITCHButton            matlab.ui.control.Button
        pZButton                matlab.ui.control.Button
        nZButton                matlab.ui.control.Button
        pXButton                matlab.ui.control.Button
        nYButton                matlab.ui.control.Button
        pYButton                matlab.ui.control.Button
        nXButton                matlab.ui.control.Button
        UICameraView            matlab.ui.control.UIAxes
        UISimulationView        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        sim; % Simulation to be accessed by the app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            app.sim = RoboTargeterSimulation(app);
        end

        % Button pushed function: pZButton
        function pZButtonPushed(app, event)
            input = [0, 0, 1, 0, 0, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: nZButton
        function nZButtonPushed(app, event)
            input = [0, 0, -1, 0, 0, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: pXButton
        function pXButtonPushed(app, event)
            input = [1, 0, 0, 0, 0, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: nXButton
        function nXButtonPushed(app, event)
            input = [-1, 0, 0, 0, 0, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: pYButton
        function pYButtonPushed(app, event)
            input = [0, 1, 0, 0, 0, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: nYButton
        function nYButtonPushed(app, event)
            input = [0, -1, 0, 0, 0, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: nROLLButton
        function nROLLButtonPushed(app, event)
            input = [0, 0, 0, -1, 0, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: pROLLButton
        function pROLLButtonPushed(app, event)
            input = [0, 0, 0, 1, 0, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: nPITCHButton
        function nPITCHButtonPushed(app, event)
            input = [0, 0, 0, 0, -1, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: pPITCHButton
        function pPITCHButtonPushed(app, event)
            input = [0, 0, 0, 0, 1, 0]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: nYAWButton
        function nYAWButtonPushed(app, event)
            input = [0, 0, 0, 0, 0, -1]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end

        % Button pushed function: pYAWButton
        function pYAWButtonPushed(app, event)
            input = [0, 0, 0, 0, 0, 1]; % Input for BotSet controller in UR3
            app.sim.laserBot.BotSet(app,input);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UISimulationView = uiaxes(app.UIFigure);
            title(app.UISimulationView, 'Simulation View')
            xlabel(app.UISimulationView, 'X')
            ylabel(app.UISimulationView, 'Y')
            zlabel(app.UISimulationView, 'Z')
            app.UICameraView = uiaxes(app.UIFigure);
            title(app.UICameraView, 'Camera View')
            xlabel(app.UICameraView, 'X')
            ylabel(app.UICameraView, 'Y')
            zlabel(app.UICameraView, 'Z')
            app.ManualMovementPanel = uipanel(app.UIFigure);
            app.ManualMovementPanel.Title = 'Manual Movement';
            app.nXButton = uibutton(app.ManualMovementPanel, 'push');
            app.nXButton.ButtonPushedFcn = createCallbackFcn(app, @nXButtonPushed, true);
            app.nXButton.Text = '-X';
            app.pYButton = uibutton(app.ManualMovementPanel, 'push');
            app.pYButton.ButtonPushedFcn = createCallbackFcn(app, @pYButtonPushed, true);
            app.pYButton.Text = '+Y';
            app.nYButton = uibutton(app.ManualMovementPanel, 'push');
            app.nYButton.ButtonPushedFcn = createCallbackFcn(app, @nYButtonPushed, true);
            app.nYButton.Text = '-Y';
            app.pXButton = uibutton(app.ManualMovementPanel, 'push');
            app.pXButton.ButtonPushedFcn = createCallbackFcn(app, @pXButtonPushed, true);
            app.pXButton.Text = '+X';
            app.nZButton = uibutton(app.ManualMovementPanel, 'push');
            app.nZButton.ButtonPushedFcn = createCallbackFcn(app, @nZButtonPushed, true);
            app.nZButton.Text = '-Z';
            app.pZButton = uibutton(app.ManualMovementPanel, 'push');
            app.pZButton.ButtonPushedFcn = createCallbackFcn(app, @pZButtonPushed, true);
            app.pZButton.Text = '+Z';
            app.nPITCHButton = uibutton(app.ManualMovementPanel, 'push');
            app.nPITCHButton.ButtonPushedFcn = createCallbackFcn(app, @nPITCHButtonPushed, true);
            app.nPITCHButton.Text = '-';
            app.nROLLButton = uibutton(app.ManualMovementPanel, 'push');
            app.nROLLButton.ButtonPushedFcn = createCallbackFcn(app, @nROLLButtonPushed, true);
            app.nROLLButton.Text = '-';
            app.pYAWButton = uibutton(app.ManualMovementPanel, 'push');
            app.pYAWButton.ButtonPushedFcn = createCallbackFcn(app, @pYAWButtonPushed, true);
            app.pYAWButton.Text = '+';
            app.pPITCHButton = uibutton(app.ManualMovementPanel, 'push');
            app.pPITCHButton.ButtonPushedFcn = createCallbackFcn(app, @pPITCHButtonPushed, true);
            app.pPITCHButton.Text = '+';
            app.nYAWButton = uibutton(app.ManualMovementPanel, 'push');
            app.nYAWButton.ButtonPushedFcn = createCallbackFcn(app, @nYAWButtonPushed, true);
            app.nYAWButton.Text = '-';
            app.pROLLButton = uibutton(app.ManualMovementPanel, 'push');
            app.pROLLButton.ButtonPushedFcn = createCallbackFcn(app, @pROLLButtonPushed, true);
            app.pROLLButton.Text = '+';
            app.ROLLLabel = uilabel(app.ManualMovementPanel);
            app.ROLLLabel.Text = 'ROLL';
            app.PITCHLabel = uilabel(app.ManualMovementPanel);
            app.PITCHLabel.Text = 'PITCH';
            app.YAWLabel = uilabel(app.ManualMovementPanel);
            app.YAWLabel.Text = 'YAW';
            app.Joint1GaugeLabel = uilabel(app.ManualMovementPanel);
            app.Joint1GaugeLabel.Text = 'Joint 1';
            app.Joint1Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint2GaugeLabel = uilabel(app.ManualMovementPanel);
            app.Joint2GaugeLabel.Text = 'Joint 2';
            app.Joint2Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint3GaugeLabel = uilabel(app.ManualMovementPanel);
            app.Joint3GaugeLabel.Text = 'Joint 3';
            app.Joint3Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint4GaugeLabel = uilabel(app.ManualMovementPanel);
            app.Joint4GaugeLabel.Text = 'Joint 4';
            app.Joint4Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint5GaugeLabel = uilabel(app.ManualMovementPanel);
            app.Joint5GaugeLabel.Text = 'Joint 5';
            app.Joint5Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint6GaugeLabel = uilabel(app.ManualMovementPanel);
            app.Joint6GaugeLabel.Text = 'Joint 6';
            app.Joint6Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.SimulationProgramPanel = uipanel(app.UIFigure);
            app.SimulationProgramPanel.Title = 'Simulation Program';
            app.InitialiseButton = uibutton(app.SimulationProgramPanel, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.EMERGENCYSTOPLabel = uilabel(app.UIFigure);
            app.EMERGENCYSTOPLabel.Text = 'EMERGENCY STOP';
            app.EmergencyStop = uiswitch(app.UIFigure, 'slider');
            app.ESTOPLamp = uilamp(app.UIFigure);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUITesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end